classdef synthesizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        IndividualSoundControlsLabel  matlab.ui.control.Label
        LowpassFilterInHzdividedby10Label  matlab.ui.control.Label
        ClearRecordButton             matlab.ui.control.Button
        PlayRecordButton              matlab.ui.control.Button
        AddtoRecordButton             matlab.ui.control.Button
        SynthesizerLabel              matlab.ui.control.Label
        CutoffFrequencySlider         matlab.ui.control.Slider
        CutoffFrequencyLabel          matlab.ui.control.Label
        RepetitionsSlider             matlab.ui.control.Slider
        RepetitionsSliderLabel        matlab.ui.control.Label
        PlaySampleButton              matlab.ui.control.Button
        WaveTypeButtonGroup           matlab.ui.container.ButtonGroup
        SquareButton                  matlab.ui.control.ToggleButton
        SinusoidButton                matlab.ui.control.ToggleButton
        SawtoothButton                matlab.ui.control.ToggleButton
        NoneButton                    matlab.ui.control.ToggleButton
        AmplitudeSlider               matlab.ui.control.Slider
        AmplitudeSliderLabel          matlab.ui.control.Label
        PitchSlider                   matlab.ui.control.Slider
        PitchSliderLabel              matlab.ui.control.Label
    end

    
    properties (Access = private)
        selectedButton = 0;
        ampValue = 10;
        pitchValue = 10;
        cutOffValue = 10;
        repetitions = 100;
        record = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeSlider
        function AmplitudeSliderValueChanged(app, event)
            app.ampValue = app.AmplitudeSlider.Value;
        end

        % Selection changed function: WaveTypeButtonGroup
        function WaveTypeButtonGroupSelectionChanged(app, event)
            app.selectedButton = app.WaveTypeButtonGroup.SelectedObject;
        end

        % Button pushed function: PlaySampleButton
        function PlaySampleButtonPushed(app, event)
            % Placeholder
            if (app.selectedButton == app.NoneButton || app.selectedButton == 0)
                waveform_wave = zeros(1, 10^2)';
            else
                wave_type = "sine";
                if (app.selectedButton == app.SinusoidButton)
                    wave_type = "sine";
                elseif (app.selectedButton == app.SawtoothButton)
                    wave_type = "sawtooth";
                elseif (app.selectedButton == app.SquareButton)
                    wave_type = "square";
                end
                osc = audioOscillator('SignalType', wave_type, 'Frequency', 1*10^8);
                waveform_wave = osc();
            end
            waveform = app.ampValue * waveform_wave;
            frequency = 1000 + 30 * app.pitchValue;
            for i=0:app.repetitions
                sound(waveform, frequency);
            end
            if (app.selectedButton ~= app.NoneButton && app.selectedButton ~= 0)
                release(osc)
            end
        end

        % Value changed function: PitchSlider
        function PitchSliderValueChanged(app, event)
            app.pitchValue = app.PitchSlider.Value;
            disp(app.pitchValue);
        end

        % Value changed function: CutoffFrequencySlider
        function CutoffFrequencySliderValueChanged(app, event)
            value = app.CutoffFrequencySlider.Value + 1;
            app.cutOffValue = value;
        end

        % Value changed function: RepetitionsSlider
        function RepetitionsSliderValueChanged(app, event)
            value = app.RepetitionsSlider.Value;
            app.repetitions = value * 10;
        end

        % Button pushed function: AddtoRecordButton
        function AddtoRecordButtonPushed(app, event)
            if (app.selectedButton == app.NoneButton || app.selectedButton == 0)
                waveform_wave = zeros(1, 10^2)';
            else
                if (app.selectedButton == app.SinusoidButton)
                    wave_type = "sine";
                elseif (app.selectedButton == app.SawtoothButton)
                    wave_type = "sawtooth";
                elseif (app.selectedButton == app.SquareButton)
                    wave_type = "square";
                end
                osc = audioOscillator('SignalType', wave_type, 'Frequency', 1*10^8);
                waveform_wave = osc();
            end
            waveform = app.ampValue * waveform_wave;
            frequency = 1000 + 30 * app.pitchValue;
            minirecord = [frequency; app.repetitions; waveform];
            app.record = [app.record, minirecord];
            if (app.selectedButton ~= app.NoneButton && app.selectedButton ~= 0)
                release(osc)
            end
        end

        % Button pushed function: ClearRecordButton
        function ClearRecordButtonPushed(app, event)
            app.record = [];
        end

        % Button pushed function: PlayRecordButton
        function PlayRecordButtonPushed(app, event)
            if (length(app.record) ~= 0)
                for i=1:length(app.record(1,:))
                    for j=1:app.record(2,i)
                        waveform = app.record(3:end, i);
                        frequency = app.record(1,i);
                        waveform_low = lowpass(waveform, 10 * app.cutOffValue, frequency);
                        sound(waveform_low, frequency);
                    end
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PitchSliderLabel = uilabel(app.UIFigure);
            app.PitchSliderLabel.Text = 'Pitch';
            app.PitchSlider = uislider(app.UIFigure);
            app.PitchSlider.ValueChangedFcn = createCallbackFcn(app, @PitchSliderValueChanged, true);
            app.AmplitudeSliderLabel = uilabel(app.UIFigure);
            app.AmplitudeSliderLabel.Text = 'Amplitude';
            app.AmplitudeSlider = uislider(app.UIFigure);
            app.AmplitudeSlider.ValueChangedFcn = createCallbackFcn(app, @AmplitudeSliderValueChanged, true);
            app.WaveTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.WaveTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WaveTypeButtonGroupSelectionChanged, true);
            app.WaveTypeButtonGroup.Title = 'Wave Type';
            app.NoneButton = uitogglebutton(app.WaveTypeButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.SawtoothButton = uitogglebutton(app.WaveTypeButtonGroup);
            app.SawtoothButton.Text = 'Sawtooth';
            app.SinusoidButton = uitogglebutton(app.WaveTypeButtonGroup);
            app.SinusoidButton.Text = 'Sinusoid';
            app.SquareButton = uitogglebutton(app.WaveTypeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.PlaySampleButton = uibutton(app.UIFigure, 'push');
            app.PlaySampleButton.ButtonPushedFcn = createCallbackFcn(app, @PlaySampleButtonPushed, true);
            app.PlaySampleButton.Text = 'Play Sample';
            app.RepetitionsSliderLabel = uilabel(app.UIFigure);
            app.RepetitionsSliderLabel.Text = 'Repetitions';
            app.RepetitionsSlider = uislider(app.UIFigure);
            app.RepetitionsSlider.ValueChangedFcn = createCallbackFcn(app, @RepetitionsSliderValueChanged, true);
            app.CutoffFrequencyLabel = uilabel(app.UIFigure);
            app.CutoffFrequencyLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencySlider = uislider(app.UIFigure);
            app.CutoffFrequencySlider.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencySliderValueChanged, true);
            app.SynthesizerLabel = uilabel(app.UIFigure);
            app.SynthesizerLabel.Text = 'Synthesizer';
            app.AddtoRecordButton = uibutton(app.UIFigure, 'push');
            app.AddtoRecordButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoRecordButtonPushed, true);
            app.AddtoRecordButton.Text = 'Add to Record';
            app.PlayRecordButton = uibutton(app.UIFigure, 'push');
            app.PlayRecordButton.ButtonPushedFcn = createCallbackFcn(app, @PlayRecordButtonPushed, true);
            app.PlayRecordButton.Text = 'Play Record';
            app.ClearRecordButton = uibutton(app.UIFigure, 'push');
            app.ClearRecordButton.ButtonPushedFcn = createCallbackFcn(app, @ClearRecordButtonPushed, true);
            app.ClearRecordButton.Text = 'Clear Record';
            app.LowpassFilterInHzdividedby10Label = uilabel(app.UIFigure);
            app.LowpassFilterInHzdividedby10Label.Text = 'Low-pass Filter (In Hz, divided by 10)';
            app.IndividualSoundControlsLabel = uilabel(app.UIFigure);
            app.IndividualSoundControlsLabel.Text = 'Individual Sound Controls';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = synthesizer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end